classdef P3aron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GraficarButton            matlab.ui.control.Button
        ButtonGroup               matlab.ui.container.ButtonGroup
        TSpinner                  matlab.ui.control.Spinner
        TSpinnerLabel             matlab.ui.control.Label
        AperiodicoButton          matlab.ui.control.RadioButton
        PeriodicoButton           matlab.ui.control.RadioButton
        TiempomnimosSlider        matlab.ui.control.Slider
        TiempomnimosSliderLabel   matlab.ui.control.Label
        MuestrasSpinner           matlab.ui.control.Spinner
        MuestrasSpinnerLabel      matlab.ui.control.Label
        FaseSpinner               matlab.ui.control.Spinner
        FaseSpinnerLabel          matlab.ui.control.Label
        FrecuenciaSpinner         matlab.ui.control.Spinner
        FrecuenciaSpinnerLabel    matlab.ui.control.Label
        TiempomaximosSlider       matlab.ui.control.Slider
        TiempomaximosSliderLabel  matlab.ui.control.Label
        TiempoButtonGroup         matlab.ui.container.ButtonGroup
        DiscretoButton            matlab.ui.control.RadioButton
        ContinuoButton            matlab.ui.control.RadioButton
        AmplitudSpinner           matlab.ui.control.Spinner
        AmplitudSpinnerLabel      matlab.ui.control.Label
        SealDropDown              matlab.ui.control.DropDown
        SealDropDownLabel         matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxesImagen              matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: SealDropDown
        function SealDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
            if(item == 1)
                global Imagen;
                app.UIAxes.Title.String = "...";
                Imagen = [];

                imshow(Imagen, "Parent", app.UIAxesImagen);


            elseif(item == 2)
                global Imagen;

                if app.DiscretoButton.Value == 1
                    Imagen = imread("Escalon_unitario_d.png");
                else
                    Imagen = imread("Escalon_unitario_c.png");
                end

                imshow(Imagen, "Parent", app.UIAxesImagen);
                app.UIAxes.Title.String = "Escalon unitario";

                
                Maximo = app.TiempomaximosSlider.Value;
                Minimo = app.TiempomnimosSlider.Value;
                Amplitud = app.AmplitudSpinner.Value;
                Frecuencia = app.FrecuenciaSpinner.Value;
                Fase = app.FaseSpinner.Value;
                T = app.TSpinner.Value;
                % Periodo = T * (1 / Frecuencia);

                % Muestras
                x = linspace(Minimo, Maximo, Maximo * app.MuestrasSpinner.Value);

                if app.AperiodicoButton.Value == 1
                    Funcion = Amplitud * heaviside(x + Fase);
                else
                    %disp(length(heaviside(x + Fase)));
                    %disp("--");
                    %disp(length(urect(x + Fase, 1 / Frecuencia, T)));
                    Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                end
                
                if(app.ContinuoButton.Value == 1)
                    plot(app.UIAxes, x, Funcion);
                else
                    app.MuestrasSpinner.Value = 10;
                    stem(app.UIAxes,x, Funcion); %Grafica
                end


            elseif(item == 3)
                global Imagen;  
                
                Imagen = imread("Impulso_unitario.png");
                imshow(Imagen, "Parent", app.UIAxesImagen);
                app.UIAxes.Title.String = "Impulso Unitario";


                Maximo = app.TiempomaximosSlider.Value;
                Minimo = app.TiempomnimosSlider.Value;
                Amplitud = app.AmplitudSpinner.Value;
                Frecuencia = app.FrecuenciaSpinner.Value;
                Fase = app.FaseSpinner.Value;
                T = app.TSpinner.Value;
                

                % Muestras
                x = linspace(Minimo, Maximo, Maximo * app.MuestrasSpinner.Value);

                if app.AperiodicoButton.Value == 1
                    %disp(app.TiempomnimosSlider.Value)
                    F_Impulso_unitario = [zeros(1, abs(app.TiempomnimosSlider.Value)), ones(1, 1), zeros(1, abs(app.TiempomaximosSlider.Value))];
                    stem(app.UIAxes, x, F_Impulso_unitario); %Grafica
                else
                    F_Impulso_unitario = [zeros(1, abs(app.TiempomnimosSlider.Value)), ones(1, 1), zeros(1, abs(app.TiempomaximosSlider.Value))];

                end
                
                % if(app.ContinuoButton.Value == 1)
                %     plot(app.UIAxes, x, Funcion);
                % else
                %     app.MuestrasSpinner.Value = 10;
                %     stem(app.UIAxes,x, Funcion); %Grafica
                % end

            elseif(item == 4)
                global Imagen;
                
                Imagen = imread("F3.jpg");
                imshow(Imagen, "Parent", app.UIAxesImagen);
                app.UIAxes.Title.String = "Rampa";

            elseif(item == 5)
                global Imagen;
                
                Imagen = imread("F4.png");
                imshow(Imagen, "Parent", app.UIAxesImagen);
                app.UIAxes.Title.String = "Exponencial";

            elseif(item == 6)
                global Imagen;
                
                Imagen = imread("F1.jpg");
                imshow(Imagen, "Parent", app.UIAxesImagen);
                app.UIAxes.Title.String = "Senoidal";


                Maximo = app.TiempomaximosSlider.Value;
                Minimo = app.TiempomnimosSlider.Value;
                Amplitud = app.AmplitudSpinner.Value;
                Frecuencia = app.FrecuenciaSpinner.Value;
                Fase = app.FaseSpinner.Value;
                T = app.TSpinner.Value;
                % Periodo = T * (1 / Frecuencia);

                % Muestras
                x = linspace(Minimo, Maximo, Maximo * app.MuestrasSpinner.Value);

                if app.AperiodicoButton.Value == 1
                    Funcion = Amplitud * sin(2*pi*x*Frecuencia + Fase);
                else
                    %disp(length(heaviside(x + Fase)));
                    %disp("--");
                    %disp(length(urect(x + Fase, 1 / Frecuencia, T)));
                    Funcion = Amplitud * sin(2*pi*x*Frecuencia*T + Fase);
                end
                
                if(app.ContinuoButton.Value == 1)
                    plot(app.UIAxes, x, Funcion);
                else
                    app.MuestrasSpinner.Value = 10;
                    stem(app.UIAxes,x, Funcion); %Grafica
                end


            end


        end

        % Value changed function: TiempomaximosSlider
        function TiempomaximosSliderValueChanged(app, event)
            ValorMaximo = app.TiempomaximosSlider.Value;
            ValorMinimo = app.TiempomnimosSlider.Value;
            Amplitud = app.AmplitudSpinner.Value;
            Frecuencia = app.FrecuenciaSpinner.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;

            plot(app.UIAxes, ValorMaximo)
            app.UIAxes.XLim = [ValorMinimo ValorMaximo];

            item = app.SealDropDown.Value;
 
            %disp(item);
            if item == "Escalón Unitario"
                ValorMinimo = app.TiempomnimosSlider.Value;
                ValorMaximo = app.TiempomaximosSlider.Value;
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
               
                % Funcion = Amplitud *ones(1, length(x));
                
                if(app.ContinuoButton.Value == 1)
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        plot(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        plot(app.UIAxes, x, Funcion); %Grafica
                    end
                else
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        stem(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        stem(app.UIAxes, x, Funcion); %Grafica
                    end
                end

            end
        end

        % Value changed function: TiempomnimosSlider
        function TiempomnimosSliderValueChanged(app, event)
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            Amplitud = app.AmplitudSpinner.Value;
            Frecuencia = app.FrecuenciaSpinner.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;

            plot(app.UIAxes, ValorMinimo);
            app.UIAxes.XLim = [ValorMinimo ValorMaximo];
            
            item = app.SealDropDown.Value;

            if item == "Escalón Unitario"
                ValorMinimo = app.TiempomnimosSlider.Value;
                ValorMaximo = app.TiempomaximosSlider.Value;
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
    
                % Funcion = Amplitud * heaviside(x);
                % Funcion = Amplitud.*ones(1, length(x));

                if(app.ContinuoButton.Value == 1)
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        plot(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        plot(app.UIAxes, x, Funcion); %Grafica
                    end
                else
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        stem(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        stem(app.UIAxes, x, Funcion); %Grafica
                    end
                end
            end
        end

        % Value changed function: AmplitudSpinner
        function AmplitudSpinnerValueChanged(app, event)
            Amplitud = app.AmplitudSpinner.Value;
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            item = app.SealDropDown.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;

            if item == "Escalón Unitario"
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
    

                if(app.ContinuoButton.Value == 1)
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        plot(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        plot(app.UIAxes, x, Funcion); %Grafica
                    end
                else
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        stem(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        stem(app.UIAxes, x, Funcion); %Grafica
                    end
                end

            end

        end

        % Selection changed function: TiempoButtonGroup
        function TiempoButtonGroupSelectionChanged(app, event)
            if app.DiscretoButton.Value == 1
                Imagen = imread("Escalon_unitario_d.png");
                app.MuestrasSpinner.Value = 10;
            else
                Imagen = imread("Escalon_unitario_c.png");
                app.MuestrasSpinner.Value = 1000;
            end

            imshow(Imagen, "Parent", app.UIAxesImagen);
    
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            Amplitud = app.AmplitudSpinner.Value;

            plot(app.UIAxes, ValorMinimo);
            app.UIAxes.XLim = [ValorMinimo ValorMaximo];
            
            item = app.SealDropDown.Value;

            if item == "Escalón Unitario"
                ValorMinimo = app.TiempomnimosSlider.Value;
                ValorMaximo = app.TiempomaximosSlider.Value;
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
                Fase = app.FaseSpinner.Value;
                T = app.TSpinner.Value;
                
                % Funcion = Amplitud.*ones(1, length(x));
                
                % if(app.ContinuoButton.Value == 1)
                %     plot(app.UIAxes, x, Funcion);
                % else
                %     app.MuestrasSpinner.Value = 10;
                %     stem(app.UIAxes, x, Funcion); %Grafica
                % end

                if(app.ContinuoButton.Value == 1)
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        plot(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        plot(app.UIAxes, x, Funcion); %Grafica
                    end
                else
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        stem(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        stem(app.UIAxes, x, Funcion); %Grafica
                    end
                end
            end

            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            Amplitud = app.AmplitudSpinner.Value;
            item = app.SealDropDown.Value;
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;

            if item == "Escalón Unitario"
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
    
                
                % Funcion = Amplitud.*ones(1, length(x));


%%ff
                if(app.ContinuoButton.Value == 1)
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        plot(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        plot(app.UIAxes, x, Funcion); %Grafica
                    end
                else
                    if(app.AperiodicoButton.Value == 1)
                        Funcion = Amplitud * heaviside(x);
                        stem(app.UIAxes, x, Funcion);
                    else
                        Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                        stem(app.UIAxes, x, Funcion); %Grafica
                    end
                end


            end


        end

        % Value changed function: TSpinner
        function TSpinnerValueChanged(app, event)
            Amplitud = app.AmplitudSpinner.Value;
            item = app.SealDropDown.Value;
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;

            if item == "Escalón Unitario" && app.PeriodicoButton.Value == 1
                x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * app.MuestrasSpinner.Value);
                
                % Funcion = Amplitud.*ones(1, length(x));
                
                % if(app.ContinuoButton.Value == 1)
                if(app.AperiodicoButton.Value == 1)   
                    % app.MuestrasSpinner.Value = 1000;
                    Funcion = Amplitud * heaviside(x);
                    plot(app.UIAxes, x, Funcion);
                else
                    % app.MuestrasSpinner.Value = 10;
                    Funcion = (Amplitud * heaviside(x + Fase)).*urect(x + Fase, 1, T);
                    stem(app.UIAxes, x, Funcion); %Grafica
                end
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            Amplitud = app.AmplitudSpinner.Value;
            item = app.SealDropDown.Value;
            ValorMinimo = app.TiempomnimosSlider.Value;
            ValorMaximo = app.TiempomaximosSlider.Value;
            Muestras = app.MuestrasSpinner.Value;
            Fase = app.FaseSpinner.Value;
            T = app.TSpinner.Value;
            Frecuencia = app.FrecuenciaSpinner.Value;
            x = linspace(ValorMinimo, ValorMaximo, ValorMaximo * Muestras);

            if item == "Escalón Unitario"
                Funcion = Amplitud * heaviside(x + Fase);
            
            elseif item == "Senoidal"
                Funcion = Amplitud * sin(2*pi*x*Frecuencia + Fase);
            end
            

            %Senal_par = 0.5 * (Funcion(x) + Funcion(-x));

            Senal_par = zeros(1, length(x));
            Senal_impar = zeros(1, length(x));

            for i = 1: length(x)
                Senal_par(i) = 0.5 * (Funcion(i) + Funcion(length(x) - i + 1));
                Senal_impar(i) = 0.5 * (Funcion(i) - Funcion(length(x) - i + 1));

            end

            plot(app.UIAxes2, x, Senal_par);
            plot(app.UIAxes3, x, Senal_impar);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '...')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesImagen = uiaxes(app.UIFigure);
            app.UIAxesImagen.XTick = [];
            app.UIAxesImagen.YTick = [];
            app.UIAxesImagen.ZTick = [];
            app.UIAxesImagen.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Señal par')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Señal impar')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal:';
            app.SealDropDown = uidropdown(app.UIFigure);
            app.SealDropDown.Items = {'...', 'Escalón Unitario', 'Impulso Unitario', 'Rampa', 'Exponencial', 'Senoidal'};
            app.SealDropDown.ClickedFcn = createCallbackFcn(app, @SealDropDownClicked, true);
            app.SealDropDown.Value = '...';
            app.AmplitudSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudSpinnerLabel.Text = 'Amplitud';
            app.AmplitudSpinner = uispinner(app.UIFigure);
            app.AmplitudSpinner.Limits = [0 Inf];
            app.AmplitudSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudSpinnerValueChanged, true);
            app.AmplitudSpinner.Value = 1;
            app.TiempoButtonGroup = uibuttongroup(app.UIFigure);
            app.TiempoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TiempoButtonGroupSelectionChanged, true);
            app.TiempoButtonGroup.Title = 'Tiempo';
            app.ContinuoButton = uiradiobutton(app.TiempoButtonGroup);
            app.ContinuoButton.Text = 'Continuo';
            app.ContinuoButton.Value = true;
            app.DiscretoButton = uiradiobutton(app.TiempoButtonGroup);
            app.DiscretoButton.Text = 'Discreto';
            app.TiempomaximosSliderLabel = uilabel(app.UIFigure);
            app.TiempomaximosSliderLabel.Text = 'Tiempo maximo (s) ';
            app.TiempomaximosSlider = uislider(app.UIFigure);
            app.TiempomaximosSlider.Limits = [0 20];
            app.TiempomaximosSlider.ValueChangedFcn = createCallbackFcn(app, @TiempomaximosSliderValueChanged, true);
            app.TiempomaximosSlider.Value = 2;
            app.FrecuenciaSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciaSpinnerLabel.Text = 'Frecuencia';
            app.FrecuenciaSpinner = uispinner(app.UIFigure);
            app.FrecuenciaSpinner.Step = 0.5;
            app.FrecuenciaSpinner.Limits = [0 Inf];
            app.FrecuenciaSpinner.Value = 1;
            app.FaseSpinnerLabel = uilabel(app.UIFigure);
            app.FaseSpinnerLabel.Text = 'Fase';
            app.FaseSpinner = uispinner(app.UIFigure);
            app.FaseSpinner.Step = 100;
            app.FaseSpinner.Limits = [0 Inf];
            app.MuestrasSpinnerLabel = uilabel(app.UIFigure);
            app.MuestrasSpinnerLabel.Text = 'Muestras';
            app.MuestrasSpinner = uispinner(app.UIFigure);
            app.MuestrasSpinner.Step = 100;
            app.MuestrasSpinner.Limits = [1 Inf];
            app.MuestrasSpinner.Value = 1000;
            app.TiempomnimosSliderLabel = uilabel(app.UIFigure);
            app.TiempomnimosSliderLabel.Text = 'Tiempo mínimo (s)';
            app.TiempomnimosSlider = uislider(app.UIFigure);
            app.TiempomnimosSlider.Limits = [-20 0];
            app.TiempomnimosSlider.ValueChangedFcn = createCallbackFcn(app, @TiempomnimosSliderValueChanged, true);
            app.TiempomnimosSlider.Value = -2;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PeriodicoButton = uiradiobutton(app.ButtonGroup);
            app.PeriodicoButton.Text = 'Periodico';
            app.AperiodicoButton = uiradiobutton(app.ButtonGroup);
            app.AperiodicoButton.Text = 'Aperiodico';
            app.AperiodicoButton.Value = true;
            app.TSpinnerLabel = uilabel(app.ButtonGroup);
            app.TSpinnerLabel.Text = 'T';
            app.TSpinner = uispinner(app.ButtonGroup);
            app.TSpinner.Limits = [1 Inf];
            app.TSpinner.ValueChangedFcn = createCallbackFcn(app, @TSpinnerValueChanged, true);
            app.TSpinner.Value = 1;
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P3aron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end