classdef Practica9C < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GraficarButton  matlab.ui.control.Button
        UIAxes_8        matlab.ui.control.UIAxes
        UIAxes_7        matlab.ui.control.UIAxes
        UIAxes_6        matlab.ui.control.UIAxes
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes_4        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)

% Parámetros
Fs = 512;          % Frecuencia de muestreo
T = 1/Fs;          % Periodo de muestreo
L = 1024;          % Longitud de la señal
t = (0:L-1)*T;     % Vector de tiempo

% Señal
s = 2*sin(2*pi*5*t) + 1.5*sin(2*pi*20*t) + 0.5*sin(2*pi*45*t); % Señal suma de senos (5, 20 y 45 Hz)

% Transformada de Fourier
S = fft(s);                   % FFT de la señal
f = (0:(L-1))*(Fs/L);         % Vector de frecuencias
S_magnitude = abs(S)/L;       % Magnitud de la FFT

% Graficar señal original
plot(app.UIAxes, t, s);
title(app.UIAxes, "SEÑAL ORIGINAL x(t)");
grid(app.UIAxes, 'on');

plot(app.UIAxes_5, f, S_magnitude);
title(app.UIAxes_5, "TRANSFORMADA DE FOURIER DE LA SEÑAL ORIGINAL");
grid(app.UIAxes_5, 'on');

% Crear el vector de frecuencias para la mitad positiva del espectro
f_pos = f(1:L/2+1);

% FILTRO PASA BAJAS
Fc_low = 7; % Frecuencia de corte
H_low = (f_pos < Fc_low); % Respuesta en frecuencia del filtro pasa bajo

% Aplicar el filtro al espectro de la señal
S_filt_low = S(1:L/2+1) .* H_low;
S_filt_low = [S_filt_low, conj(S_filt_low(end-1:-1:2))]; % Conjugado simétrico
s_filt_low = real(ifft(S_filt_low)); % Transformada Inversa de Fourier

% Graficar señal filtrada pasa bajo
plot(app.UIAxes_2, t, s_filt_low);
title(app.UIAxes_2, "SEÑAL FILTRADA PASO BAJO");
grid(app.UIAxes_2, 'on');

plot(app.UIAxes_6, f, abs(S_filt_low)/L);
title(app.UIAxes_6, "TRANSFORMADA DE FOURIER DE LA SEÑAL FILTRADA PASO BAJO");
grid(app.UIAxes_6, 'on');

% FILTRO PASA ALTAS
Fc_high = 40; % Frecuencia de corte
H_high = (f_pos > Fc_high); % Respuesta en frecuencia del filtro pasa alto

% Aplicar el filtro al espectro de la señal
S_filt_high = S(1:L/2+1) .* H_high;
S_filt_high = [S_filt_high, conj(S_filt_high(end-1:-1:2))]; % Conjugado simétrico
s_filt_high = real(ifft(S_filt_high)); % Transformada Inversa de Fourier

% Graficar señal filtrada pasa alto
plot(app.UIAxes_3, t, s_filt_high);
title(app.UIAxes_3, "SEÑAL FILTRADA PASO ALTO");
grid(app.UIAxes_3, 'on');

plot(app.UIAxes_7, f, abs(S_filt_high)/L);
title(app.UIAxes_7, "TRANSFORMADA DE FOURIER DE LA SEÑAL FILTRADA PASO ALTO");
grid(app.UIAxes_7, 'on');

% FILTRO PASABANDA
Fc_low_band = 15; % Frecuencia de corte baja
Fc_high_band = 30; % Frecuencia de corte alta
H_band = (f_pos > Fc_low_band & f_pos < Fc_high_band); % Respuesta en frecuencia del filtro pasabanda

% Aplicar el filtro al espectro de la señal
S_filt_band = S(1:L/2+1) .* H_band;
S_filt_band = [S_filt_band, conj(S_filt_band(end-1:-1:2))]; % Conjugado simétrico
s_filt_band = real(ifft(S_filt_band)); % Transformada Inversa de Fourier

% Graficar señal filtrada pasabanda
plot(app.UIAxes_4, t, s_filt_band);
title(app.UIAxes_4, "SEÑAL FILTRADA PASO BANDA");
grid(app.UIAxes_4, 'on');

plot(app.UIAxes_8, f, abs(S_filt_band)/L);
title(app.UIAxes_8, "TRANSFORMADA DE FOURIER DE LA SEÑAL FILTRADA PASO BANDA");
grid(app.UIAxes_8, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtro Pasa Bajas')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Filtro Pasa Altas')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtro Pasabanda')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Transformada de Fourier_ Señal Original')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Transformada de Fourier_ FPB')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Transformada de Fourier_FPA')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Transformada de Fourier_ FPB')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica9C

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end