classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ContinuarButton       matlab.ui.control.Button
        EliminarvectorButton  matlab.ui.control.Button
        AgregarvectorButton   matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        DefinirvectoresLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function results = valoresIniciales(app)
            results = [0 0;0 0];
        end
        
        function results = agregarVector(app, tabla)
            results = [tabla;0 0];
        end
        
        function results = eliminarVector(app, tabla)
            results = tabla(1:end-1,1:end);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tabla)
            app.UITable.Data = valoresIniciales(app);
        end

        % Button pushed function: AgregarvectorButton
        function AgregarvectorButtonPushed(app, event)
            app.UITable.Data = agregarVector(app,app.UITable.Data);
        end

        % Button pushed function: EliminarvectorButton
        function EliminarvectorButtonPushed(app, event)
            app.UITable.Data = eliminarVector(app,app.UITable.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Visible = 'off';
            app.DefinirvectoresLabel = uilabel(app.GridLayout);
            app.DefinirvectoresLabel.Text = 'Definir vectores';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AgregarvectorButton = uibutton(app.GridLayout, 'push');
            app.AgregarvectorButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarvectorButtonPushed, true);
            app.AgregarvectorButton.Text = 'Agregar vector';
            app.EliminarvectorButton = uibutton(app.GridLayout, 'push');
            app.EliminarvectorButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarvectorButtonPushed, true);
            app.EliminarvectorButton.Text = 'Eliminar vector';
            app.ContinuarButton = uibutton(app.GridLayout, 'push');
            app.ContinuarButton.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end