classdef p3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        NPeriodosEditField              matlab.ui.control.NumericEditField
        NPeriodosEditFieldLabel         matlab.ui.control.Label
        RepresentacinLabel              matlab.ui.control.Label
        TipoButtonGroup                 matlab.ui.container.ButtonGroup
        ContinuaButton                  matlab.ui.control.RadioButton
        DiscretaButton                  matlab.ui.control.RadioButton
        DescomponersealButton           matlab.ui.control.StateButton
        FrecuenciaEditField             matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        AmplitudEditField               matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel          matlab.ui.control.Label
        MuestreoEditField               matlab.ui.control.NumericEditField
        MuestreoEditFieldLabel          matlab.ui.control.Label
        MnEditField                     matlab.ui.control.NumericEditField
        MnEditFieldLabel                matlab.ui.control.Label
        TiempoLabel                     matlab.ui.control.Label
        FuncinDropDown                  matlab.ui.control.DropDown
        FuncinDropDownLabel             matlab.ui.control.Label
        ConfiguresusealLabel            matlab.ui.control.Label
        SeleccionalasealagraficarLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        SEALESELEMENTALESLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        funcion;
        tiempo;
        rango = 0;
    end
    
    methods (Access = public)
        
        function esconderDatos(app) %esta parte sirve para borrar todo del canva (menos el que selecciona a la señal)
            app.ConfiguresusealLabel.Visible = "off";
            app.FuncinDropDown.Visible = "off";
            app.FuncinDropDownLabel.Visible = "off";
            app.TiempoLabel.Visible = "off";
            app.MnEditField.Visible = "off";
            app.MnEditFieldLabel.Visible = "off";
            app.MaxEditField.Visible = "off";
            app.MaxEditFieldLabel.Visible = "off";
            app.MuestreoEditField.Visible = "off";
            app.MuestreoEditFieldLabel.Visible = "off";
            app.AmplitudEditField.Visible = "off";
            app.AmplitudEditFieldLabel.Visible = "off";
            app.FrecuenciaEditField.Visible = "off";
            app.FrecuenciaEditFieldLabel.Visible = "off";
            app.FrecuenciaEditFieldLabel.Visible = "off";
            app.TipoButtonGroup.Visible = "off";
            app.PeriodicidadButtonGroup.Visible = "off";
            app.DescomponersealButton.Visible = "off";
            app.RepresentacinLabel.Visible = "off";
            app.UIAxes.Visible = "off";
            app.NPeriodosEditField.Visible = "off";
            app.NPeriodosEditFieldLabel.Visible = "off";
            app.GraficarButton.Visible = "off";
        end

        function mostrarDatos(app) %esta parte sirve para mostrar todo del canva
            app.ConfiguresusealLabel.Visible = "on";
            app.FuncinDropDown.Visible = "on";
            app.FuncinDropDownLabel.Visible = "on";
            app.TiempoLabel.Visible = "on";
            app.MnEditField.Visible = "on";
            app.MnEditFieldLabel.Visible = "on";
            app.MaxEditField.Visible = "on";
            app.MaxEditFieldLabel.Visible = "on";
            app.MuestreoEditField.Visible = "on";
            app.MuestreoEditFieldLabel.Visible = "on";
            app.AmplitudEditField.Visible = "on";
            app.AmplitudEditFieldLabel.Visible = "on";
            app.FrecuenciaEditField.Visible = "on";
            app.FrecuenciaEditFieldLabel.Visible = "on";
            app.TipoButtonGroup.Visible = "on";
            app.DescomponersealButton.Visible = "on";
            app.RepresentacinLabel.Visible = "on";
            app.UIAxes.Visible = "on";
            app.NPeriodosEditField.Visible = "on";
            app.NPeriodosEditFieldLabel.Visible = "on";
            app.GraficarButton.Visible = "on";
        end

        function mostrarPorFuncion(app)
            app.mostrarDatos();
            
            switch app.DropDown.Value
                case 'Senoidal'
                    app.FuncinDropDown.Visible = "on";
                    app.FuncinDropDownLabel.Visible = "on";
                case 'Escalón unitario'
                    app.FuncinDropDown.Visible = "off";
                    app.FuncinDropDownLabel.Visible = "off";
                    app.FrecuenciaEditField.Visible = "off";
                    app.FrecuenciaEditFieldLabel.Visible = "off";
                    app.NPeriodosEditField.Visible = "off";
                    app.NPeriodosEditFieldLabel.Visible = "off";
                    app.MnEditField.Visible = "off";
                    app.MnEditFieldLabel.Visible = "off";
                case 'Impulso unitario'
                    app.FuncinDropDown.Visible = "off";
                    app.FuncinDropDownLabel.Visible = "off";
                otherwise
                    app.FuncinDropDown.Visible = "off";
                    app.FuncinDropDownLabel.Visible = "off";
            end
        end

        function graficar(app)
            switch app.DropDown.Value %aquí se grafican todas las funciones
                case '-'
                    app.esconderDatos();
                case 'Senoidal'
                    app.mostrarDatos();
                case 'Exponencial'
                    app.mostrarDatos();
                case 'Rampa'
                    app.mostrarDatos();
                case 'Cuadrada'
                    app.graficarCuadrada();
                case 'Triangular'
                    app.graficarTriangular();
                case 'Escalón unitario'
                    app.graficarEscalon();
                case 'Impulso unitario'
                    app.graficarImpulso();
            end
        end

                function descomponer(app)
            
            t = app.tiempo;
            rango = app.rango;
            x = app.funcion;

            for k=1: length(x)
                x_r(k) = x(length(x)-k+1);
            end

            x_par = (x+x_r)/2;
            x_impar = (x-x_r)/2;

            if rango == 0
                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t,x_par,"Color",'r');
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,t,x_impar,"Color",'g');
                    hold(app.UIAxes, 'off');
                else
                    stem(app.UIAxes,t,x_par,"Color",'r');
                    hold(app.UIAxes, 'on');
                    stem(app.UIAxes,t,x_impar,"Color",'g');
                    hold(app.UIAxes, 'off');
                end
            else
                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t(rango),x_par(rango),"Color",'r');
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,t(rango),x_impar(rango),"Color",'g');
                    hold(app.UIAxes, 'off');
                else
                    stem(app.UIAxes,t(rango),x_par(rango),"Color",'r');
                    hold(app.UIAxes, 'on');
                    stem(app.UIAxes,t(rango),x_impar(rango),"Color",'g');
                    hold(app.UIAxes, 'off');
                end
            end

            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
            title(app.UIAxes,"Señal cuadrada aperiódica");
            axis(app.UIAxes, 'auto');
        end

        function graficarCuadrada(app)
            amplitud = app.AmplitudEditField.Value;
            muestreo = app.MuestreoEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            min = app.MnEditField.Value;
            max = app.MaxEditField.Value;
            periodos = app.NPeriodosEditField.Value * (1/frecuencia);

            t = 0:1/muestreo:periodos-1/muestreo;
            rango = t >= min & t <= max;

            x = amplitud*square(2*pi*frecuencia*t,50);

            if app.NPeriodosEditField.Value == 1
                t = min:1/muestreo:max;
                x = amplitud * rectpuls(t,frecuencia);

                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t,x);
                else
                    stem(app.UIAxes,t,x);
                end
                app.rango = 0;
            else
                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t(rango),x(rango));
                else
                    stem(app.UIAxes,t(rango),x(rango));
                end
                app.rango = rango;
            end

            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
            title(app.UIAxes,"Señal cuadrada aperiódica");
            axis(app.UIAxes, 'auto');
            app.funcion = x;
            app.tiempo = t;
        end

        function graficarTriangular(app)
            amplitud = app.AmplitudEditField.Value;
            muestreo = app.MuestreoEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            min = app.MnEditField.Value;
            max = app.MaxEditField.Value;
            periodos = app.NPeriodosEditField.Value * (1/frecuencia);

            t = 0:1/muestreo:periodos-1/muestreo;
            rango = t >= min & t <= max;

            x = amplitud*sawtooth(2*pi*frecuencia*t,0.5); %nos da un rectangulo centrado en t=0

            if app.NPeriodosEditField.Value == 1
                t = min:1/muestreo:max;
                x = amplitud * tripuls(t,frecuencia);

                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t,x);
                else
                    stem(app.UIAxes,t,x);
                end
                app.rango = 0;
            else
                if app.ContinuaButton.Value == 1
                plot(app.UIAxes,t(rango),x(rango));
                else
                    stem(app.UIAxes,t(rango),x(rango));
                end
                app.rango = rango;
            end

            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
            title(app.UIAxes,"Señal cuadrada aperiódica");
            axis(app.UIAxes, 'auto');
            app.funcion = x;
            app.tiempo = t;
        end

        function graficarEscalon(app)

            amplitud = app.AmplitudEditField.Value;
            muestreo = app.MuestreoEditField.Value;
            max = app.MaxEditField.Value;

            t = 0:1/muestreo:max;

            x = amplitud*ones(size(t));

            if app.ContinuaButton.Value == 1
                plot(app.UIAxes,t,x);
            else
                stem(app.UIAxes,t,x);
            end
           
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
            title(app.UIAxes,"Señal escalón unitario");
            axis(app.UIAxes, 'auto');
            app.funcion = x;
            app.tiempo = t;
            app.rango = 0;
        end

        function graficarImpulso(app)
            amplitud = app.AmplitudEditField.Value;
            min = app.MnEditField.Value;
            max = app.MaxEditField.Value;
            muestreo = app.MuestreoEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            periodos = app.NPeriodosEditField.Value;

            t = min:1/muestreo:max;

            impulse = t==frecuencia;

            if periodos == 1
                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t,impulse*amplitud);
                else
                    stem(app.UIAxes,t,impulse*amplitud);
                end
                app.funcion = impulse*amplitud;
            else
                t = min:1/muestreo:min+1*periodos+1;
                x = 0;
                for k = min+1 : (min+1*periodos)
                    impulse = t==k;
                    x = x + impulse;
                end

                if app.ContinuaButton.Value == 1
                    plot(app.UIAxes,t,x*amplitud);
                else
                    stem(app.UIAxes,t,x*amplitud);
                end
                app.funcion = x*amplitud;
            end
           
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Tiempo (s)');
            ylabel(app.UIAxes,'Amplitud');
            title(app.UIAxes,"Señal impulso unitario");
            axis(app.UIAxes, 'auto');
            app.tiempo = t;
            app.rango = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.esconderDatos();
            app.MnEditField.Value = -1;
            %app.MaxEditField.Value = 1;
            app.MuestreoEditField.Value = 10;
            app.AmplitudEditField.Value = 1;
            app.FrecuenciaEditField.Value = 0.125;
            app.ContinuaButton.Value = 1;
            app.NPeriodosEditField.Value = 1;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.mostrarPorFuncion();
        end

        % Selection changed function: TipoButtonGroup
        function TipoButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %{
            
            periodisidad = app.SiButton.Value;

            if periodisidad == 1
                disp("Graficando periodisidad");
                app.graficarPeriodica();
            else
                disp("Graficando aperiodisidad");
                app.graficarAperiodica();
            end
            %}
            app.graficar();
        end

        % Callback function
        function PeriodicidadButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: DescomponersealButton
        function DescomponersealButtonValueChanged(app, event)
            app.descomponer();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SEALESELEMENTALESLabel = uilabel(app.UIFigure);
            app.SEALESELEMENTALESLabel.Text = 'SEÑALES ELEMENTALES';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'-', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón unitario', 'Impulso unitario'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '-';
            app.SeleccionalasealagraficarLabel = uilabel(app.UIFigure);
            app.SeleccionalasealagraficarLabel.Text = 'Selecciona la señal a graficar:';
            app.ConfiguresusealLabel = uilabel(app.UIFigure);
            app.ConfiguresusealLabel.Text = 'Configure su señal:';
            app.FuncinDropDownLabel = uilabel(app.UIFigure);
            app.FuncinDropDownLabel.Text = 'Función';
            app.FuncinDropDown = uidropdown(app.UIFigure);
            app.FuncinDropDown.Items = {'Seno', 'Coseno'};
            app.FuncinDropDown.Value = 'Seno';
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo';
            app.MnEditFieldLabel = uilabel(app.UIFigure);
            app.MnEditFieldLabel.Text = 'Mín';
            app.MnEditField = uieditfield(app.UIFigure, 'numeric');
            app.MnEditField.ValueDisplayFormat = '%.2f';
            app.MuestreoEditFieldLabel = uilabel(app.UIFigure);
            app.MuestreoEditFieldLabel.Text = 'Muestreo';
            app.MuestreoEditField = uieditfield(app.UIFigure, 'numeric');
            app.MuestreoEditField.ValueDisplayFormat = '%.2f';
            app.MuestreoEditField.Value = 10000;
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField.ValueDisplayFormat = '%.2f';
            app.AmplitudEditField.Value = 1;
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditField.ValueDisplayFormat = '%.2f';
            app.DescomponersealButton = uibutton(app.UIFigure, 'state');
            app.DescomponersealButton.ValueChangedFcn = createCallbackFcn(app, @DescomponersealButtonValueChanged, true);
            app.DescomponersealButton.Text = 'Descomponer señal';
            app.TipoButtonGroup = uibuttongroup(app.UIFigure);
            app.TipoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipoButtonGroupSelectionChanged, true);
            app.TipoButtonGroup.Title = 'Tipo:';
            app.DiscretaButton = uiradiobutton(app.TipoButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.ContinuaButton = uiradiobutton(app.TipoButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.RepresentacinLabel = uilabel(app.UIFigure);
            app.RepresentacinLabel.Text = 'Representación';
            app.NPeriodosEditFieldLabel = uilabel(app.UIFigure);
            app.NPeriodosEditFieldLabel.Text = 'N. Periodos';
            app.NPeriodosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPeriodosEditField.ValueDisplayFormat = '%.0f';
            app.NPeriodosEditField.Value = 1;
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueDisplayFormat = '%.2f';
            app.MaxEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end