classdef practica2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        AnteriorButton   matlab.ui.control.Button
        SiguienteButton  matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        contador = 0;
    end
    methods (Access = public)
        %Sen 7
        function [valoresFrecCardiacaYActividad,datos] = calcFrecCardiaca(app,numValores)
            %Se defina la fecuencia minima y maxima
            minFrecuencia = 60;
            maxFrecuencia = 200;
            %Definimos un incremento definido
            incrementoPorActividad = 9;
            %Se crea un vector para indicar la actividad fisica que vaya del 0 al
            %15
            actividadFisica = 0:numValores-1;
            %matriz para almacenar la actividad y la frec cardiaca
            valoresFrecCardiacaYActividad = zeros(2,numValores);
            %vector para almacenar la fecuencia cardiaca
            valoresFrecCardiaca = zeros(1,numValores);
            %Valores aleatorios para cada numero
            variabilidadIndividual = randi([-2, 15], 1, numValores);
            %Se crea un vector para las fecuencias cardiacas que vaya aumentando a
            for i = 1:numValores
                %se calcula la frecuencia cardiaca
                frecCardiaca = minFrecuencia+(actividadFisica(i)*incrementoPorActividad)+variabilidadIndividual(i);
                %se comprueba que no rebase el limite
                if frecCardiaca >maxFrecuencia
                    frecCardiaca = maxFrecuencia;
                end
                %se almacena la frecuencia cardiaca
                valoresFrecCardiaca(i) = frecCardiaca;
            end
            datos = ["Una variable","Escalar","Discreta","Discreta","Aleatoria","Se cuenta con una sola variable ya que su ritmo cardiaco se puede solo medir con los beats por minuto, es escalar ya que...., Es discreta porque como esta de manera digital todas las señales pasan a ser discretas"];
            valoresFrecCardiacaYActividad(1,1:end) = valoresFrecCardiaca;
            valoresFrecCardiacaYActividad(2,1:end) = actividadFisica;
        end
        %Sen 8
        function [valoresFloracion,datos] = calcFloracion(app,tempMaxima)
            %Se define una temperatura minima
            tempMinima = -5;
            floracion = zeros(1,tempMaxima-tempMinima+1);
            valTemperaturas = -5:tempMaxima;
            valoresFloracion = zeros(2,tempMaxima-tempMinima+1);
            incrementoFlores = 10;
            incrementoAleatorioFlores = randi([0, 30], 1, tempMaxima-tempMinima+1);
            for i =1:tempMaxima-tempMinima+1
                if valTemperaturas(i) < 1
                    floracion(i) = floracion(i)+0;
                elseif valTemperaturas(i) > 35
                    floracion(i) = floracion(i-1)-(incrementoAleatorioFlores(i)*valTemperaturas(i));
                else
                    floracion(i) = floracion(i-1)+(incrementoAleatorioFlores(i)*valTemperaturas(i));
                end
            end
            datos = ["Una variable","Escalar","Discreta","Discreta","Aleatoria","Se cuenta con una sola variable ya que solo se esta midiendo la floracion de acuerdo a la temperatura"];
            valoresFloracion(1,1:end) = valTemperaturas;
            valoresFloracion(2,1:end) = floracion;
        end
        %Sen 9
        function [valoresAves,datos] = simularPatronMigratorio(app,maxNumComida)
            ubiLista = zeros(1,maxNumComida+1);
            comida = 0:maxNumComida;
            valAleatorios = randi([2,100],1,maxNumComida+1);
            valoresAves = zeros(2,maxNumComida+1);
        
            for i=1:maxNumComida+1
                ubiLista(i) = comida(i)*valAleatorios(i);
            end
            datos = ["Una variable","Escalar","Discreta","Discreta","Aleatoria","Se cuenta con una sola variable ya que solo se esta midiendo la floracion de acuerdo a la temperatura"];
            valoresAves(1,1:end) = comida;
            valoresAves(2,1:end) = ubiLista;
        end
        %Sen 10
        function [valoresHojas,datos] = simularHojas(app,maxNumMinutos)
            %almacenar los datos generado
            valoresHojas = zeros(2,maxNumMinutos+1);
            %crear lista con el numero maximo de minutos
            minutos = 0:maxNumMinutos;
            %temp maxima y minima para calcular la coloracion
            tempMin = 2000;
            tempMax = 7500;
            %valores aleatorios para simular el aumento en la coloracion
            valAleatorios = randi([1,500],1,maxNumMinutos+1);
            incremento = 200;
            %se calcula la temperatura de acuerdo a los minutos que dure el
            %dia, se multiplica un valor aleatorio por los minutos
            for i=1:maxNumMinutos+1
                temperatura = tempMin+(minutos(i)*incremento)+valAleatorios(i);
                if temperatura > tempMax
                    temperatura =tempMax;
                end
                valoresHojas(1,i) = temperatura;
            end
            datos = ["Una variable","Escalar","Discreta","Discreta","Aleatoria","Se cuenta con una sola variable ya que solo se esta midiendo la floracion de acuerdo a la temperatura"];
            valoresHojas(2,1:end) = minutos;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            app.contador = app.contador+1;
            if app.contador > 10
                app.contador = 1;
            end
            if app.contador == 1
                %Senal 1
            elseif app.contador == 2
                %Senal 2
            elseif app.contador == 3
                %Senal 3
            elseif app.contador == 4
                %Senal 4
            elseif app.contador == 5
                %Senal 5
            elseif app.contador == 6
                %Senal 6
            elseif app.contador == 7
                [valoresSen7,datos] = calcFrecCardiaca(app,16);
                plot(app.UIAxes,valoresSen7(2,1:end),valoresSen7(1,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Ritmo cardiaco (bpm)')
                xlabel(app.UIAxes,'Minutos de actividad fisica')
                title(app.UIAxes,'Ritmo cardiaco de un animal')
                app.UITable.Data = datos;
            elseif app.contador == 8
                [valoresSen8 ,datos]= calcFloracion(app,45);
                plot(app.UIAxes,valoresSen8(1,1:end),valoresSen8(2,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'cantidad de hojas')
                xlabel(app.UIAxes,'Temperatura registrada (Celcius)')
                title(app.UIAxes,'Floracion de plantas')
                app.UITable.Data = datos;
            elseif app.contador == 9
                [valoresSen9,datos] = simularPatronMigratorio(app,100);
                plot(app.UIAxes,valoresSen9(1,1:end),valoresSen9(2,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Cantidad que avanza una parvada (km)')
                xlabel(app.UIAxes,'Cantidad de comida (Kg)')
                title(app.UIAxes,'Patron migratorio de aves')
                app.UITable.Data = datos;
            elseif app.contador == 10
                [valoresSen10,datos] = simularHojas(app,24);
                plot(app.UIAxes,valoresSen10(2,1:end),valoresSen10(1,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Temperatura de la imagen de la hoja (Kelvin)')
                xlabel(app.UIAxes,'Horas que dura el dia')
                title(app.UIAxes,'Cambio de color de las hojas en otoño')
                app.UITable.Data = datos;
            end
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            app.contador = app.contador-1;
            if app.contador < 1
                app.contador = 10;
            end
            if app.contador == 1
                %Senal 1
            elseif app.contador == 2
                %Senal 2
            elseif app.contador == 3
                %Senal 3
            elseif app.contador == 4
                %Senal 4
            elseif app.contador == 5
                %Senal 5
            elseif app.contador == 6
                %Senal 6
            elseif app.contador == 7
                [valoresSen7,datos] = calcFrecCardiaca(app,16);
                plot(app.UIAxes,valoresSen7(2,1:end),valoresSen7(1,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Ritmo cardiaco (bpm)')
                xlabel(app.UIAxes,'Minutos de actividad fisica')
                title(app.UIAxes,'Ritmo cardiaco de un animal')
                app.UITable.Data = datos;
            elseif app.contador == 8
                [valoresSen8 ,datos]= calcFloracion(app,45);
                plot(app.UIAxes,valoresSen8(1,1:end),valoresSen8(2,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'cantidad de hojas')
                xlabel(app.UIAxes,'Temperatura registrada (Celcius)')
                title(app.UIAxes,'Floracion de plantas')
                app.UITable.Data = datos;
            elseif app.contador == 9
                [valoresSen9,datos] = simularPatronMigratorio(app,100);
                plot(app.UIAxes,valoresSen9(1,1:end),valoresSen9(2,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Cantidad que avanza una parvada (km)')
                xlabel(app.UIAxes,'Cantidad de comida (Kg)')
                title(app.UIAxes,'Patron migratorio de aves')
                app.UITable.Data = datos;
            elseif app.contador == 10
                [valoresSen10,datos] = simularHojas(app,24);
                plot(app.UIAxes,valoresSen10(2,1:end),valoresSen10(1,1:end),'-o', 'Color', 'red')
                ylabel(app.UIAxes,'Temperatura de la imagen de la hoja (Kelvin)')
                xlabel(app.UIAxes,'Horas que dura el dia')
                title(app.UIAxes,'Cambio de color de las hojas en otoño')
                app.UITable.Data = datos;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Num. Variables'; 'Dimension'; 'Variables'; 'Valores'; 'Estadisticas'; 'Justificacion'};
            app.UITable.RowName = {};
            app.SiguienteButton = uibutton(app.GridLayout, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.AnteriorButton = uibutton(app.GridLayout, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end