classdef practica3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TiempomaxEditField             matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel        matlab.ui.control.Label
        TiempominEditField             matlab.ui.control.NumericEditField
        TiempominEditFieldLabel        matlab.ui.control.Label
        FrecuenciademuestreoEditField  matlab.ui.control.NumericEditField
        FrecuenciademuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel         matlab.ui.control.Label
        RepresentacionButtonGroup      matlab.ui.container.ButtonGroup
        DiscretaButton                 matlab.ui.control.RadioButton
        ContinuaButton                 matlab.ui.control.RadioButton
        SealDropDown                   matlab.ui.control.DropDown
        SealDropDownLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function  ploteacion = graficarSenoidal(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            %t = linspace(tmin,tmax,((tmax- tmin)*1000)/app.FrecuenciademuestreoEditField.Value);
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;
            y = amplitud*sin(2*pi*frecuencia*t2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            end
        end

        % Triangular
        function  ploteacion = graficarTriangular(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*sawtooth(2*pi*frecuencia*t2,0.5);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            end
        end

        % Cuadrada
        function  ploteacion = graficarCuadrada(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*square(2*pi*frecuencia*t2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
            end
        end
        % Rampa
        function ploteacion = graficarRampa(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
        
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
        
            % Generar la señal de rampa
            y = amplitud * t2 .* (t2 >= 0);
        
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        %Escalon Unitariooo --------------
        function ploteacion = graficarEscalonUnitario(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de escalón unitario
            y = amplitud * (t2 >= 0);
            
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        
        %Impulso Unitario --------------
        % Función impulso unitario
        function ploteacion = graficarImpulsoUnitario(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            % Calcular el vector de tiempo
            t2 = tmin:0.01:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de impulso unitario
            y = amplitud * (t2 == 0);
            
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        % Pulso Rectangular
        function ploteacion = graficarPulsoRectangular(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            ancho_pulso = 1; % Ancho del pulso
            
            % Calcular el vector de tiempo
            t2 = tmin:0.01:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de pulso rectangular
            y = amplitud * rectpuls((t2-((tmin+tmax)/2))/ancho_pulso, ancho_pulso);
            
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        % Exponencial -----------
        function ploteacion = graficarExponencial(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            tau = 1; % Parámetro de tiempo característico
            
            % Calcular el vector de tiempo
            t2 = tmin:0.01:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal exponencial
            y = amplitud * exp(-(t2-tmin)/tau);
            
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            switch app.SealDropDown.Value
                case 'Senoidal'
                    graficarSenoidal(app)
                case 'Triangular'
                    graficarTriangular(app)
                case 'Cuadrada'
                    graficarCuadrada(app)
                case 'Rampa'
                    graficarRampa(app)
                case 'EscalonUnitario'
                graficarEscalonUnitario(app);
                case 'ImpulsoUnitario'
                    graficarImpulsoUnitario(app);
                case 'PulsoRectangular'
                    graficarPulsoRectangular(app);
                case 'Exponencial'
                    graficarExponencial(app);
                otherwise
                %%PAra más funciones
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SealDropDownLabel = uilabel(app.GridLayout);
            app.SealDropDownLabel.Text = 'Señal ';
            app.SealDropDown = uidropdown(app.GridLayout);
            app.SealDropDown.Items = {'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon unitario', 'Impulso unitario', 'Impulso triangular'};
            app.SealDropDown.Value = 'Senoidal';
            app.RepresentacionButtonGroup = uibuttongroup(app.GridLayout);
            app.RepresentacionButtonGroup.Title = 'Representacion';
            app.ContinuaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.DiscretaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudEditField.Value = 1;
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 1;
            app.FrecuenciademuestreoEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciademuestreoEditFieldLabel.Text = 'Frecuencia de muestreo';
            app.FrecuenciademuestreoEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciademuestreoEditField.Value = 0.2;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end