classdef practica4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UsarSealGeneradaCheckBox       matlab.ui.control.CheckBox
        VariableescalamientoEditField  matlab.ui.control.NumericEditField
        VariableescalamientoEditFieldLabel  matlab.ui.control.Label
        OperacionDepDropDown           matlab.ui.control.DropDown
        OperacionDepDropDownLabel      matlab.ui.control.Label
        AmplitudEditField_2            matlab.ui.control.NumericEditField
        AmplitudEditField_2Label       matlab.ui.control.Label
        TiempomaxEditField_2           matlab.ui.control.NumericEditField
        TiempomaxEditField_2Label      matlab.ui.control.Label
        TiempominEditField_2           matlab.ui.control.NumericEditField
        TiempominEditField_2Label      matlab.ui.control.Label
        FrecuenciaEditField_2          matlab.ui.control.NumericEditField
        FrecuenciaEditField_2Label     matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        RealizaroperacionButton        matlab.ui.control.Button
        SealBDropDown                  matlab.ui.control.DropDown
        SealBDropDownLabel             matlab.ui.control.Label
        OperacionIndepDropDown         matlab.ui.control.DropDown
        OperacionIndepDropDownLabel    matlab.ui.control.Label
        TiempomaxEditField             matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel        matlab.ui.control.Label
        TiempominEditField             matlab.ui.control.NumericEditField
        TiempominEditFieldLabel        matlab.ui.control.Label
        FrecuenciademuestreoEditField  matlab.ui.control.NumericEditField
        FrecuenciademuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel         matlab.ui.control.Label
        RepresentacionButtonGroup      matlab.ui.container.ButtonGroup
        DiscretaButton                 matlab.ui.control.RadioButton
        ContinuaButton                 matlab.ui.control.RadioButton
        SealADropDown                  matlab.ui.control.DropDown
        SealADropDownLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        signalA = 0
        signalB = 0
        signalResultado = 0
    end
    
    methods (Access = public)
        
        function  y = graficarSenoidal(app, amplitud, tmin, tmax,frecuencia,axes)
            %t = linspace(tmin,tmax,((tmax- tmin)*1000)/app.FrecuenciademuestreoEditField.Value);
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;
            y = amplitud*sin(2*pi*frecuencia*t2);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        % Triangular
        function  y = graficarTriangular(app,amplitud, tmin, tmax,frecuencia,axes)
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*sawtooth(2*pi*frecuencia*t2,0.5);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        % Cuadrada
        function  y = graficarCuadrada(app,amplitud, tmin, tmax,frecuencia,axes)
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*square(2*pi*frecuencia*t2);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        % Rampa
        function y = graficarRampa(app,amplitud, tmin, tmax,frecuencia,axes)
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
        
            % Generar la señal de rampa
            y = amplitud * t2 .* (t2 >= 0);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        %Escalon Unitariooo --------------
        function y = graficarEscalonUnitario(app,amplitud, tmin, tmax,frecuencia,axes)
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            % Generar la señal de escalón unitario
            y = amplitud*heaviside(t2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        
        %Impulso Unitario --------------
        % Función impulso unitario
        function y = graficarImpulsoUnitario(app,amplitud, tmin, tmax,frecuencia,axes) 
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de impulso unitario
            y = zeros(size(t2));
            y(t2 == 0) = amplitud;
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        % Pulso Rectangular
        function y = graficarPulsoRectangular(app,amplitud, tmin, tmax,frecuencia,axes)
            ancho_pulso = 1; % Ancho del pulso
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            % Generar la señal de pulso rectangular
            y = amplitud * rectangularPulse(t2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        %Pulso triangular
        function y = graficarImpulsoTriangular(app,amplitud, tmin, tmax,frecuencia,axes)
            ancho = 1;
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;% Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            % Generar la señal de pulso rectangular
            y = amplitud*triangularPulse(t2);   
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        % Exponencial -----------
        function y = graficarExponencial(app,amplitud, tmin, tmax,frecuencia,axes)
            tau = 1; % Parámetro de tiempo característico
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            % Generar la señal exponencial
            y = exp(t2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = graficarExponencialDec(app,amplitud, tmin, tmax,frecuencia,axes)
            tau = 1; % Parámetro de tiempo característico
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            r=1.5;
            xd = exp(t2);
            % Generar la señal exponencial
            y = r.^(-t2);
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %OPERACIONES
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function y = suma(app, axes, signalA, signalB)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = signalA + signalB;
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = multiplicacion(app, axes, signalA, signalB)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = signalA.*signalB;
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = escalamientoAmplitud(app, axes, signalA, variable)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = variable.*signalA;
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = diferenciacion(app, axes, signalA,fs)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = diff(signalA)/app.FrecuenciademuestreoEditField.Value;
            if app.DiscretaButton.Value == 1
                stem(axes, t2(:,1:length(y)), y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2(:,1:length(y)), y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = integracion(app, axes, signalA)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = cumtrapz(t2,signalA);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %OPERACIONES INDEPENDIENTE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function y = reflexion(app, axes, signalA)
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            y = fliplr(signalA);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end

        function y = corrimiento(app, axes, signalA, corrimiento)
            %t = linspace(tmin,tmax,((tmax- tmin)*1000)/app.FrecuenciademuestreoEditField.Value);
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            n = length(signalA);
            indices_desplazados = mod((0:n-1) - corrimiento, n) + 1;
            y = signalA(indices_desplazados);
            if app.DiscretaButton.Value == 1
                stem(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
        function y = escalamientoTiempo(app, axes, signalA, escalamiento)
            %t = linspace(tmin,tmax,((tmax- tmin)*1000)/app.FrecuenciademuestreoEditField.Value);
            t2 = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value:app.TiempomaxEditField.Value;
            %tEscalado = app.TiempominEditField.Value:app.FrecuenciademuestreoEditField.Value/escalamiento:app.TiempomaxEditField.Value;
            %y = interp1(t2,signalA,tEscalado,'linear');
            y = signalA;
            if app.DiscretaButton.Value == 1
                stem(axes, escalamiento*t2, y, 'LineWidth', 1, 'Color', 'r');
            else
                plot(axes, escalamiento*t2, y, 'LineWidth', 1, 'Color', 'r');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OperacionDepDropDown.Visible = 'off';
            app.OperacionDepDropDownLabel.Visible = 'off';
            app.OperacionIndepDropDown.Visible = 'on';
            app.OperacionIndepDropDownLabel.Visible = 'on';
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %%Señal 1
            amplitud = app.AmplitudEditField.Value;
            tiempoMin = app.TiempominEditField.Value;
            tiempoMax = app.TiempomaxEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            axes = app.UIAxes2;
            switch app.SealADropDown.Value
                case 'Senoidal'
                    app.signalA = graficarSenoidal(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Triangular'
                    app.signalA = graficarTriangular(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Cuadrada'
                    app.signalA = graficarCuadrada(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Rampa'
                    app.signalA = graficarRampa(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Escalon unitario'
                    app.signalA = graficarEscalonUnitario(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Impulso unitario'
                    app.signalA = graficarImpulsoUnitario(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Impulso rectangular'
                    app.signalA = graficarPulsoRectangular(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Impulso triangular'
                    app.signalA = graficarImpulsoTriangular(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Exponencial'
                    app.signalA = graficarExponencial(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                case 'Exp. decreciente'
                    app.signalA = graficarExponencialDec(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                otherwise
                %%PAra más funciones
            end
            %%Señal 2
            amplitud2 = app.AmplitudEditField_2.Value;
            tiempoMin2 = app.TiempominEditField_2.Value;
            tiempoMax2 = app.TiempomaxEditField_2.Value;
            frecuencia2 = app.FrecuenciaEditField_2.Value;
            axes2 = app.UIAxes2_2;
            switch app.SealBDropDown.Value
                case 'Senoidal'
                    app.signalB = graficarSenoidal(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Triangular'
                    app.signalB = graficarTriangular(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Cuadrada'
                    app.signalB = graficarCuadrada(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Rampa'
                    app.signalB = graficarRampa(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Escalon unitario'
                    app.signalB = graficarEscalonUnitario(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Impulso unitario'
                    app.signalB = graficarImpulsoUnitario(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Impulso rectangular'
                    app.signalB = graficarPulsoRectangular(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Impulso triangular'
                    app.signalB = graficarImpulsoTriangular(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Exponencial'
                    app.signalB = graficarExponencial(app,amplitud2,tiempoMin2,tiempoMax2,frecuencia2,axes2);
                case 'Exp. decreciente'
                    app.signalB = graficarExponencialDec(app,amplitud,tiempoMin,tiempoMax,frecuencia,axes);
                otherwise
                %%PAra más funciones
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Independiente"
                app.OperacionDepDropDown.Visible = 'off';
                app.OperacionDepDropDownLabel.Visible = 'off';
                app.OperacionIndepDropDown.Visible = 'on';
                app.OperacionIndepDropDownLabel.Visible = 'on';
            elseif value == "Dependiente"
                app.OperacionDepDropDown.Visible = 'on';
                app.OperacionDepDropDownLabel.Visible = 'on';
                app.OperacionIndepDropDown.Visible = 'off';
                app.OperacionIndepDropDownLabel.Visible = 'off';
            end
        end

        % Button pushed function: RealizaroperacionButton
        function RealizaroperacionButtonPushed(app, event)
            axes = app.UIAxes;
            if app.UsarSealGeneradaCheckBox.Value == 1
                signalUsar = app.signalResultado;
            else
                signalUsar = app.signalA; 
            end
            if app.Switch.Value == "Independiente"
                switch app.OperacionIndepDropDown.Value
                    case 'Reflexion'
                        app.signalResultado = reflexion(app,axes,signalUsar);
                    case 'Corrimiento en tiempo'
                        app.signalResultado = corrimiento(app, axes, signalUsar, app.VariableescalamientoEditField.Value);
                    case 'Escalamiento de tiempo'
                        app.signalResultado = escalamientoTiempo(app, axes, signalUsar, app.VariableescalamientoEditField.Value);
                    otherwise
                    %%PAra más funciones
                end
            elseif app.Switch.Value == "Dependiente"
                switch app.OperacionDepDropDown.Value
                    case 'Suma'
                        app.signalResultado = suma(app,axes,signalUsar,app.signalB);
                        disp(app.signalResultado)
                    case 'Multiplicacion'
                        app.signalResultado = multiplicacion(app,axes,signalUsar,app.signalB);
                    case 'Escalamiento de amplitud'
                        app.signalResultado = escalamientoAmplitud(app,axes,signalUsar,app.VariableescalamientoEditField.Value);
                    case 'Diferenciacion'
                        app.signalResultado = diferenciacion(app,axes,signalUsar,app.FrecuenciademuestreoEditField.Value);
                    case 'Integracion'
                        app.signalResultado = integracion(app,axes,signalUsar);
                    otherwise
                    %%PAra más funciones
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, 'Señal B')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Señal A')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Resultado')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SealADropDownLabel = uilabel(app.GridLayout);
            app.SealADropDownLabel.Text = 'Señal  A';
            app.SealADropDown = uidropdown(app.GridLayout);
            app.SealADropDown.Items = {'Senoidal', 'Exponencial', 'Exponencial compleja', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon unitario', 'Impulso unitario', 'Impulso triangular', 'Impulso rectangular', 'Exp. decreciente'};
            app.SealADropDown.Value = 'Senoidal';
            app.RepresentacionButtonGroup = uibuttongroup(app.GridLayout);
            app.RepresentacionButtonGroup.Title = 'Representacion';
            app.ContinuaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.DiscretaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudEditField.Value = 1;
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 1;
            app.FrecuenciademuestreoEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciademuestreoEditFieldLabel.Text = 'Frecuencia de muestreo';
            app.FrecuenciademuestreoEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciademuestreoEditField.Value = 0.2;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 10;
            app.OperacionIndepDropDownLabel = uilabel(app.GridLayout);
            app.OperacionIndepDropDownLabel.Text = 'Operacion Indep.';
            app.OperacionIndepDropDown = uidropdown(app.GridLayout);
            app.OperacionIndepDropDown.Items = {'Escalamiento de tiempo', 'Reflexion', 'Corrimiento en tiempo'};
            app.OperacionIndepDropDown.Value = 'Escalamiento de tiempo';
            app.SealBDropDownLabel = uilabel(app.GridLayout);
            app.SealBDropDownLabel.Text = 'Señal  B';
            app.SealBDropDown = uidropdown(app.GridLayout);
            app.SealBDropDown.Items = {'Senoidal', 'Exponencial', 'Exponencial compleja', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon unitario', 'Impulso unitario', 'Impulso triangular', 'Impulso rectangular', 'Exp. decreciente'};
            app.SealBDropDown.Value = 'Senoidal';
            app.RealizaroperacionButton = uibutton(app.GridLayout, 'push');
            app.RealizaroperacionButton.ButtonPushedFcn = createCallbackFcn(app, @RealizaroperacionButtonPushed, true);
            app.RealizaroperacionButton.Text = 'Realizar operacion';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Independiente', 'Dependiente'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Independiente';
            app.FrecuenciaEditField_2Label = uilabel(app.GridLayout);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField_2.Value = 1;
            app.TiempominEditField_2Label = uilabel(app.GridLayout);
            app.TiempominEditField_2Label.Text = 'Tiempo min';
            app.TiempominEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField_2Label = uilabel(app.GridLayout);
            app.TiempomaxEditField_2Label.Text = 'Tiempo max';
            app.TiempomaxEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField_2.Value = 10;
            app.AmplitudEditField_2Label = uilabel(app.GridLayout);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.AmplitudEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudEditField_2.Value = 1;
            app.OperacionDepDropDownLabel = uilabel(app.GridLayout);
            app.OperacionDepDropDownLabel.Text = 'Operacion. Dep';
            app.OperacionDepDropDown = uidropdown(app.GridLayout);
            app.OperacionDepDropDown.Items = {'Escalamiento de amplitud', 'Suma', 'Multiplicacion', 'Diferenciacion', 'Integracion'};
            app.OperacionDepDropDown.Value = 'Escalamiento de amplitud';
            app.VariableescalamientoEditFieldLabel = uilabel(app.GridLayout);
            app.VariableescalamientoEditFieldLabel.Text = 'Variable escalamiento';
            app.VariableescalamientoEditField = uieditfield(app.GridLayout, 'numeric');
            app.VariableescalamientoEditField.Value = 2;
            app.UsarSealGeneradaCheckBox = uicheckbox(app.GridLayout);
            app.UsarSealGeneradaCheckBox.Text = 'Usar Señal Generada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end