classdef practica6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        IncisoDropDown            matlab.ui.control.DropDown
        IncisoDropDownLabel       matlab.ui.control.Label
        ProblemaDropDown          matlab.ui.control.DropDown
        ProblemaDropDownLabel     matlab.ui.control.Label
        TiempomaxEditField        matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel   matlab.ui.control.Label
        TiempominEditField        matlab.ui.control.NumericEditField
        TiempominEditFieldLabel   matlab.ui.control.Label
        FrecuenciaEditField       matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        GraficarButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function  ploteacion = graficarSenoidal(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            
            % Definición de la matriz de tiempo
            t2 = linspace(tmin, tmax, ((tmax - tmin) * 1000) / app.FrecuenciaEditField.Value);
    
            y = amplitud*sin(2*pi*frecuencia*t2);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            f_neg_t = amplitud * sin(-2 * pi * frecuencia * t2);
            f_par = 0.5 * (f_t + f_neg_t);    % Componente par
            f_impar = 0.5 * (f_t - f_neg_t);  % Componente impar
            %calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end

        % Triangular
        function  ploteacion = graficarTriangular(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciaEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*sawtooth(2*pi*frecuencia*t2,0.5);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            fNeg = amplitud*sawtooth(2*pi*frecuencia*-t2,0.5);
            f_par = 0.5 * (f_t + fliplr(f_t));
            f_impar = 0.5 * (f_t - fliplr(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end

        % Cuadrada
        function  ploteacion = graficarCuadrada(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciaEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*square(2*pi*frecuencia*t2);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            f_par = 0.5 * (f_t + fliplr(f_t));
            f_impar = 0.5 * (f_t - fliplr(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        function graficarProblema1(app)
            app.FrecuenciaEditField.Value = 200;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs = app.FrecuenciaEditField.Value;
            t = tmin:1/fs:tmax;
            y=sin(4*pi*t)+sin(8*pi*t);
            plot(app.UIAxes,t,y,'LineWidth',1,'Color','r');
            title(app.UIAxes,"x(t)")
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
        end
        function graficarProblema2(app)
            app.FrecuenciaEditField.Value = 10;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs = app.FrecuenciaEditField.Value;
            t = tmin:1/fs:tmax;
            y=sin(4*pi*t)+sin(8*pi*t);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t,y,'LineWidth',1,'Color','r');
            stem(app.UIAxes,t,y,'LineWidth',1,'Color','g');
            title(app.UIAxes,"Muestreo a 10Hz")
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
            hold(app.UIAxes, 'off');
        end
        function graficarProblema3(app)
            app.FrecuenciaEditField.Value = 10;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            %fs = app.FrecuenciaEditField.Value;
            %t = tmin:1/fs:tmax;
            %tContinuo = tmin:1/fs:tmax;
            %fContinuo = 200;
            %y=sin(4*pi*t)+sin(8*pi*t);
            %suma = zeros(1,100);
            
            %interpolacion
            %for n = 1:length(tContinuo)
                %suma = 0;
                %for i = 1:fs
                 %  x = sin(4*pi*(i/fs)) + sin (8*pi*(i/fs)); 
                 %  h = t -(i/fs);   
                 %  g = sinc(fs * h); 
                 %  aux = x*g;
                 %  suma = aux + suma;
                %end
                %reconstruida(n) = suma;
            %end
            Fs = app.FrecuenciaEditField.Value; 
            t = tmin:1/200:tmax; %Tiempo cuasi-continuo
            t2 = tmin:1/Fs:tmax; %10 Hz
            
            x2 = sin(4*pi*t)+sin(8*pi*t);
            x3 = sin(4*pi*t2)+sin(8*pi*t2);
            
            %%%%%%%%%%%%%%%%%%%%%%INTERPOLACION%%%%%%%%%%%%%%
            
            suma = app.interpolacion(t,Fs);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes,t,x2,"red")
            hold(app.UIAxes, 'on');
            %plot(app.UIAxes,t,y,'LineWidth',1,'Color','r');
            %stem(app.UIAxes,t,y,'LineWidth',1,'Color','g');
            %plot(app.UIAxes,t,suma,'LineWidth',1,'Color','blue');
            plot(app.UIAxes,t,suma,"cyan")
            plot(app.UIAxes,t2,x3,"green")
            title(app.UIAxes,"Interpolacion")
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
            hold(app.UIAxes, 'off');
        end
        function graficarProblema4(app)
            app.FrecuenciaEditField.Value = 800;
            f = 300;
            tmin = 0;
            tmax = 0.015;
            fs = app.FrecuenciaEditField.Value;
            t = tmin:1/fs:tmax;
            t2 =  tmin:1/(100*f):tmax;
            y=sin(2*pi*f*t);
            y2=sin(2*pi*f*t2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t,y,'LineWidth',1,'Color','r');
            stem(app.UIAxes,t,y,'LineWidth',1,'Color','g');
            plot(app.UIAxes,t2,y2,'LineWidth',1,'Color','blue');
            title(app.UIAxes,"Muestreo Sinusoidal a 800Hz")
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
            hold(app.UIAxes, 'off');
        end
        function graficarProblema5(app)
            %frecuencia de muestreo a 200Hz
            app.FrecuenciaEditField.Value = 200;
            
            tmin = 0;
            tmax = 0.020;
            
            fs215 = 215;
            fs305 = 305;
            fs395 = 395;
            fs500 = 500;
            
            %t215 = tmin:1/fs215:tmax;%muestreo a 215Hz
            %y215=sin(2*pi*f*t215); %sen a 215
            %t305 = tmin:1/fs305:tmax;%muestreo a 305Hz
            %y305=sin(2*pi*f*t305); %sen a 305
            %t395 = tmin:1/fs395:tmax;%muestreo a 395Hz
            %y395=sin(2*pi*f*t395); %sen a 395
            %t500 = tmin:1/fs500:tmax;%muestreo a 500Hz
            %y500=sin(2*pi*f*t500); %sen a 500
            switch app.IncisoDropDown.Value
                %Sen 125Hz
                case 'a'
                    
                    f = 125; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM = tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 125Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 125 Original
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 125Hz a 800Hz")
                case 'b'
                    
                    f = 215; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 215Hz a 800Hz")
                case 'c'
                    
                    f = 305; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 305Hz a 800Hz")
                case 'd'
                    
                    f = 395; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 395Hz a 800Hz")
                case 'e'
                    f = 500; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 500Hz a 800Hz")
            end
            
            
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
            hold(app.UIAxes, 'off');
        end
        function graficarProblema6(app)
            %frecuencia de muestreo a 200Hz
            app.FrecuenciaEditField.Value = 200;
            tmin = 0;
            tmax = 0.040;
            
            
            fs215 = 215;
            fs305 = 305;
            fs395 = 395;
            fs500 = 500;
            
            %t215 = tmin:1/fs215:tmax;%muestreo a 215Hz
            %y215=sin(2*pi*f*t215); %sen a 215
            %t305 = tmin:1/fs305:tmax;%muestreo a 305Hz
            %y305=sin(2*pi*f*t305); %sen a 305
            %t395 = tmin:1/fs395:tmax;%muestreo a 395Hz
            %y395=sin(2*pi*f*t395); %sen a 395
            %t500 = tmin:1/fs500:tmax;%muestreo a 500Hz
            %y500=sin(2*pi*f*t500); %sen a 500
            switch app.IncisoDropDown.Value
                %Sen 125Hz
                case 'a'
                    
                    f = 125; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM = tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 125Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 125 Original
                    interpolada = app.interpolacion2(t ,fs,f);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Interpolacion seno de 125Hz a 800Hz")
                case 'b'
                    f = 215; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    interpolada = app.interpolacion2(t ,fs,f);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 215Hz a 800Hz")
                case 'c'
                    f = 305; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    interpolada = app.interpolacion2(t ,fs,f);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 305Hz a 800Hz")
                case 'd'
                    f = 395; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    interpolada = app.interpolacion2(t ,fs,f);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 395Hz a 800Hz")
                case 'e'
                    f = 500; %frecuencia
                    fs = 800; %frecuencia muestreo
                    tM= tmin:1/fs:tmax;%tiempo en 800Hz
                    t =  tmin:1/(100*f):tmax;%tiempo en 215Hz Original
                    yM=sin(2*pi*f*tM); %sen muestreado a 800
                    y=sin(2*pi*f*t);%sen a 215 Original
                    interpolada = app.interpolacion2(t ,fs,f);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,tM,yM,'LineWidth',1,'Color','r');
                    stem(app.UIAxes,tM,yM,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,t,y,'LineWidth',1,'Color','blue');
                    title(app.UIAxes,"Muestreo seno de 500Hz a 800Hz")
            end
            
            plot(app.UIAxes,t,interpolada,'LineWidth',1,'Color','cyan');
            xlabel(app.UIAxes,"tiempo")
            ylabel(app.UIAxes,"Amplitud")
            hold(app.UIAxes, 'off');
        end
        

        function interpolada = interpolacion(app,t,Fs)
            interpolada = zeros(size(t));
            for j = 1:length(t)
                auxSuma = 0;
                for i = 1:Fs
                   x = sin(4*pi*(i/Fs)) + sin (8*pi*(i/Fs));
                   %g = sinc(Fs*t-i);
                   %aux = x*g;
                   %suma = aux + suma;
                   g=sinc((t(j)*Fs)-i);
                   xdd = x*g;
                   auxSuma = auxSuma + xdd;
                end
                interpolada(j) = auxSuma;
            end
        end
        function interpolada = interpolacion2(app,t,Fs,f)
            interpolada = zeros(size(t));
            for j = 1:length(t)
                auxSuma = 0;
                for i = 1:Fs
                   x = sin(2 * pi * f * (i/Fs));
                   %g = sinc(Fs*t-i);
                   %aux = x*g;
                   %suma = aux + suma;
                   g=sinc((t(j)*Fs)-i);
                   xdd = x*g;
                   auxSuma = auxSuma + xdd;
                end
                interpolada(j) = auxSuma;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.UIAxes)
            switch app.ProblemaDropDown.Value
                case '1'
                    graficarProblema1(app)
                case '2'
                    graficarProblema2(app)
                case '3'
                    graficarProblema3(app)
                case '4'
                    graficarProblema4(app)
                case '5'
                    graficarProblema5(app)
                case '6'
                    graficarProblema6(app)
                otherwise
                %%PAra más funciones
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 200;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 5;
            app.ProblemaDropDownLabel = uilabel(app.GridLayout);
            app.ProblemaDropDownLabel.Text = 'Problema';
            app.ProblemaDropDown = uidropdown(app.GridLayout);
            app.ProblemaDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProblemaDropDown.Value = '1';
            app.IncisoDropDownLabel = uilabel(app.GridLayout);
            app.IncisoDropDownLabel.Text = 'Inciso';
            app.IncisoDropDown = uidropdown(app.GridLayout);
            app.IncisoDropDown.Items = {'a', 'b', 'c', 'd', 'e'};
            app.IncisoDropDown.Value = 'a';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end