classdef practica9p1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DominioDropDown           matlab.ui.control.DropDown
        DominioDropDownLabel      matlab.ui.control.Label
        ProblemaDropDown          matlab.ui.control.DropDown
        ProblemaDropDownLabel     matlab.ui.control.Label
        TiempomaxEditField        matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel   matlab.ui.control.Label
        TiempominEditField        matlab.ui.control.NumericEditField
        TiempominEditFieldLabel   matlab.ui.control.Label
        FrecuenciaEditField       matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        GraficarButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        senal % Description
        senTransformada
        p1
        t2
        t3
        vectorFrecuencias
    end
    
    methods (Access = public)
        function graficarProblema1(app)
            %Parámetros
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs = app.FrecuenciaEditField.Value; %Frecuencia de muestreo en Hz
            t = tmin:1/fs:tmax-1/fs; %Tiempo en segundos
            L = length(t);
            app.t2 = t;
            %generar una señal ECG sintetica
            ecg_signal = 1*sin(2*pi*100*t) + sin(2*pi*500*t) + sin(2*pi*3000*t);
            ecg_signal = ecg_signal + 0.5*randn(1, L); %Añadir ruido blanco
            app.senal = ecg_signal;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t,ecg_signal,'LineWidth',1,'Color','#DC3A20');
            title(app.UIAxes,"Señal de ruido ambiental generada")
            xlim(app.UIAxes,[tmin,tmax])
            xlabel(app.UIAxes,"Tiempo")
            ylabel(app.UIAxes,"Amplitud")
            legend(app.UIAxes,'ECG')
            hold(app.UIAxes, 'off');
            
        end
        function graficarProblema2(app)
            fs = app.FrecuenciaEditField.Value;
            t = app.t2;
            senAmplitud = 2*app.senal;
            reflexion = fliplr(senAmplitud);
            nuevaLong = 2^nextpow2(length(reflexion));
            nuevaSen = zeros(1,nuevaLong);
            nuevaSen(1:length(reflexion)) = reflexion;
            app.senTransformada = nuevaSen;
            tNuevo = linspace(0, length(nuevaSen)/fs, length(nuevaSen));
            app.t3 = tNuevo;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t, app.senal,'LineWidth',1,'Color','r');
            plot(app.UIAxes,tNuevo,nuevaSen,'LineWidth',1,'Color','g');
            title(app.UIAxes,"Transformacion de la señal (Escalamiento de amplitud, reflexion, agregar ceros)")
            xlabel(app.UIAxes,"Tiempo")
            ylabel(app.UIAxes,"Amplitud")
            xlim(app.UIAxes,[0,inf])
            legend(app.UIAxes,'Señal original','Señal transformada')
            hold(app.UIAxes, 'off');
        end
        function graficarProblema3(app)
            L = length(app.t3);
            X = fft(app.senTransformada);
            fs = app.FrecuenciaEditField.Value;
            frecuencias_fft = (0:L-1) * fs / L;
            frecuencias_fft = frecuencias_fft(1:floor(L/2));
            amplitudNormalizada = abs(X(1:floor(L/2))) / max(abs(X));
            app.vectorFrecuencias = frecuencias_fft;
            app.p1 = amplitudNormalizada;
            plot(app.UIAxes, frecuencias_fft, amplitudNormalizada, "cyan");
            title(app.UIAxes, "Espectro de amplitud de la FFT");
            xlabel(app.UIAxes, "Frecuencia [Hz]");
            ylabel(app.UIAxes, "Amplitud Normalizada");
            xlim(app.UIAxes, [0, inf]);
            hold(app.UIAxes, 'off');
        end
        function graficarPasaBajas(app)
            n = 5;
            fc = 1000;
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(25,wn,'low');
            sfiltrada = filter(filtro,1,app.senTransformada);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    
                    plot(app.UIAxes,app.t3,app.senTransformada,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.t3,sfiltrada,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-bajas con 1000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.senTransformada);
                    filtradaF = fft(sfiltrada);
                    filtradaF = abs(filtradaF(1:floor(L/2))) / max(abs(filtradaF));
                    hold(app.UIAxes, 'on');
                    
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-bajas con 1000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarPasaAltas(app)
            fc = 500;
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(25,wn,'high');
            sfiltrada = filter(filtro,1,app.senTransformada);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    
                    plot(app.UIAxes,app.t3,app.senTransformada,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.t3,sfiltrada,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-altas con 500Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.t3);
                    filtradaF = fft(sfiltrada);
                    filtradaF = abs(filtradaF(1:floor(L/2))) / max(abs(filtradaF));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-altas con 500Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarPasaBandas(app)
            fc = [500,2000];
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(25,wn,'bandpass');
            sfiltrada = filter(filtro,1,app.senTransformada);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');  
                    plot(app.UIAxes,app.t3,app.senTransformada,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.t3,sfiltrada,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-bandas con 500 y 2000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.t3);
                    filtradaF = fft(sfiltrada);
                    filtradaF = abs(filtradaF(1:floor(L/2))) / max(abs(filtradaF));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',1,'Color','r');
                    title(app.UIAxes,"Filtro pasa-bandas con 500 y 2000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarRechazaBandas(app)
            fc = [500,2000];
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(25,wn,'stop');
            sfiltrada = filter(filtro,1,app.senTransformada);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.t3,sfiltrada,'LineWidth',1,'Color','r');
                    plot(app.UIAxes,app.t3,app.senTransformada,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro rechaza-bandas con 500 y 2000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.t3);
                    filtradaF = fft(sfiltrada);
                    filtradaF = abs(filtradaF(1:floor(L/2))) / max(abs(filtradaF));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',3,'Color','r');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro rechaza-bandas con 500 y 2000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.UIAxes)
            switch app.ProblemaDropDown.Value
                case 'Generar señal'
                    graficarProblema1(app)
                case 'Transformar señal'
                    graficarProblema2(app)
                case 'Transformada de fourier'
                    graficarProblema3(app)
                case 'Pasa-bajas'
                    graficarPasaBajas(app)
                case 'Pasa-altas'
                    graficarPasaAltas(app)
                case 'Pasa-bandas'
                    graficarPasaBandas(app)
                case 'Rechaza-banda'
                    graficarRechazaBandas(app)
                otherwise
                %%PAra más funciones
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 8000;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 0.2;
            app.ProblemaDropDownLabel = uilabel(app.GridLayout);
            app.ProblemaDropDownLabel.Text = 'Problema';
            app.ProblemaDropDown = uidropdown(app.GridLayout);
            app.ProblemaDropDown.Items = {'Generar señal', 'Transformar señal', 'Transformada de fourier', 'Pasa-altas', 'Pasa-bajas', 'Pasa-bandas', 'Rechaza-banda'};
            app.ProblemaDropDown.Value = 'Generar señal';
            app.DominioDropDownLabel = uilabel(app.GridLayout);
            app.DominioDropDownLabel.Text = 'Dominio';
            app.DominioDropDown = uidropdown(app.GridLayout);
            app.DominioDropDown.Items = {'Tiempo', 'Frecuencia'};
            app.DominioDropDown.Value = 'Tiempo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica9p1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end