classdef practica9p2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DominioDropDown           matlab.ui.control.DropDown
        DominioDropDownLabel      matlab.ui.control.Label
        ProblemaDropDown          matlab.ui.control.DropDown
        ProblemaDropDownLabel     matlab.ui.control.Label
        TiempomaxEditField        matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel   matlab.ui.control.Label
        TiempominEditField        matlab.ui.control.NumericEditField
        TiempominEditFieldLabel   matlab.ui.control.Label
        FrecuenciaEditField       matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        GraficarButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        senales % Description
        senSuma
        p1
        t2
        vectorFrecuencias
    end
    
    methods (Access = public)
        function graficarProblema1(app)
            % Parámetros
            frecuencias = [50, 100, 200, 400, 800, 1600, 3200, 6400];
            %fs = 20000;  % Frecuencia de muestreo
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs = app.FrecuenciaEditField.Value;
            %t = tmin:1/fs:tmax;
            t = 0:1/fs:1-1/fs;
            app.t2 = t;
            %disp(t)
            % Generar señales senoidales
            sens = zeros(length(frecuencias), length(t));
            for i = 1:length(frecuencias)
                sens(i, :) = sin(2 * pi * frecuencias(i) * t);
            end
            app.senales = sens;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t,sens(1,1:end),'LineWidth',1,'Color','#DC3A20');
            plot(app.UIAxes,t,sens(2,1:end),'LineWidth',1,'Color','#65DB0E');
            plot(app.UIAxes,t,sens(3,1:end),'LineWidth',1,'Color','#EE21B9');
            plot(app.UIAxes,t,sens(4,1:end),'LineWidth',1,'Color','#2093DC');
            plot(app.UIAxes,t,sens(5,1:end),'LineWidth',1,'Color','#7821EE');
            plot(app.UIAxes,t,sens(6,1:end),'LineWidth',1,'Color','#21B3EE');
            plot(app.UIAxes,t,sens(7,1:end),'LineWidth',1,'Color','#EEBC21');
            plot(app.UIAxes,t,sens(8,1:end),'LineWidth',1,'Color','#27EE21');
            title(app.UIAxes,"Señales senoidales con diferentes frecuencias")
            xlim(app.UIAxes,[tmin,tmax])
            xlabel(app.UIAxes,"Tiempo")
            ylabel(app.UIAxes,"Amplitud")
            legend(app.UIAxes,'50Hz', '100Hz', '200Hz', '400Hz','800Hz', '1600Hz', '3200Hz', '6400Hz')
            hold(app.UIAxes, 'off');
            
        end
        function graficarProblema2(app)
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs = app.FrecuenciaEditField.Value;
            t = app.t2;
            %suma = sin(2 * pi * 50 * t) + sin(2 * pi * 100 * t)+sin(2 * pi * 200 * t)+sin(2 * pi * 400 * t)+sin(2 * pi * 800 * t)+sin(2 * pi * 1600 * t)+sin(2 * pi * 3200 * t)+sin(2 * pi * 6400 * t);
            suma = sum(app.senales,1);
            app.senSuma = suma;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,t,suma,'LineWidth',1,'Color','g');
            title(app.UIAxes,"Suma de las señales senoidales generadas")
            xlabel(app.UIAxes,"Tiempo")
            ylabel(app.UIAxes,"Amplitud")
            xlim(app.UIAxes,[tmin,tmax])
            legend(app.UIAxes,'Señal sumada')
            hold(app.UIAxes, 'off');
        end
        function graficarProblema3(app)
            L = length(app.senSuma);
            X = fft(app.senSuma);
            fs = app.FrecuenciaEditField.Value;
            frecuencias_fft = (0:length(X)-1) * fs / length(X);
            frecuencias_fft = frecuencias_fft(1:fs/2);
            amplitudNormalizada = abs(X(1:L/2)) / max(abs(X));
            amplitudNormalizada = amplitudNormalizada(1:L/2);
            %f = app.FrecuenciaEditField.Value.*(0:(L/2)-1)./L; %vector de frecuencias
            app.vectorFrecuencias = frecuencias_fft;
            %app.p1 = 2.*(abs(X(1:L/2)/L));
            app.p1 = amplitudNormalizada;
            plot(app.UIAxes,frecuencias_fft,amplitudNormalizada,"cyan")
            title(app.UIAxes,"Espectro de amplitud de la FFT")
            xlabel(app.UIAxes,"Frecuencia")
            ylabel(app.UIAxes,"Amplitud")
            xlim(app.UIAxes,[0,inf])
            hold(app.UIAxes, 'off');
        end
        function graficarPasaBajas(app)
            n = 5;
            fc = 1000;
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(100,wn,'low');
            sfiltrada = filter(filtro,1,app.senSuma);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.t2,sfiltrada,'LineWidth',1,'Color','r');
                    plot(app.UIAxes,app.t2,app.senSuma,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-bajas con 1000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,app.TiempomaxEditField.Value])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.senSuma);
                    filtradaF = fft(sfiltrada);
                    filtradaF = 2.*(abs(filtradaF(1:L/2)/L));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',3,'Color','r');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-bajas con 1000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarPasaAltas(app)
            fc = 500;
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(100,wn,'high');
            sfiltrada = filter(filtro,1,app.senSuma);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.t2,sfiltrada,'LineWidth',1,'Color','r');
                    plot(app.UIAxes,app.t2,app.senSuma,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-altas con 500Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,app.TiempomaxEditField.Value])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.senSuma);
                    filtradaF = fft(sfiltrada);
                    filtradaF = 2.*(abs(filtradaF(1:L/2)/L));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',3,'Color','r');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-altas con 500Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarPasaBandas(app)
            fc = [500,2000];
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(100,wn,'bandpass');
            sfiltrada = filter(filtro,1,app.senSuma);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.t2,sfiltrada,'LineWidth',1,'Color','r');
                    plot(app.UIAxes,app.t2,app.senSuma,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-bandas con 500 y 2000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,app.TiempomaxEditField.Value])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.senSuma);
                    filtradaF = fft(sfiltrada);
                    filtradaF = 2.*(abs(filtradaF(1:L/2)/L));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',3,'Color','r');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro pasa-bandas con 500 y 2000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        function graficarRechazaBandas(app)
            fc = [500,2000];
            fs = app.FrecuenciaEditField.Value;
            wn = fc/(fs/2);
            %[num,den] = butter(n,wn,"low");
            filtro = fir1(100,wn,'stop');
            sfiltrada = filter(filtro,1,app.senSuma);
            
            switch app.DominioDropDown.Value
                case 'Tiempo'
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.t2,sfiltrada,'LineWidth',1,'Color','r');
                    plot(app.UIAxes,app.t2,app.senSuma,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro rechaza-bandas con 500 y 2000Hz en el dominio del tiempo")
                    xlabel(app.UIAxes,"Tiempo")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[app.TiempominEditField.Value,app.TiempomaxEditField.Value])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
                case 'Frecuencia'
                    L = length(app.senSuma);
                    filtradaF = fft(sfiltrada);
                    filtradaF = 2.*(abs(filtradaF(1:L/2)/L));
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.vectorFrecuencias,filtradaF,'LineWidth',3,'Color','r');
                    plot(app.UIAxes,app.vectorFrecuencias,app.p1,'LineWidth',1,'Color','g');
                    title(app.UIAxes,"Filtro rechaza-bandas con 500 y 2000Hz en el dominio de la frecuencia")
                    xlabel(app.UIAxes,"Frecuencia")
                    ylabel(app.UIAxes,"Amplitud")
                    xlim(app.UIAxes,[0,inf])
                    legend(app.UIAxes,'Señal filtrada','Señal original')
                    hold(app.UIAxes, 'off');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.UIAxes)
            switch app.ProblemaDropDown.Value
                case 'Generar señales'
                    graficarProblema1(app)
                case 'Sumar señales'
                    graficarProblema2(app)
                case 'Transformada de fourier'
                    graficarProblema3(app)
                case 'Pasa-bajas'
                    graficarPasaBajas(app)
                case 'Pasa-altas'
                    graficarPasaAltas(app)
                case 'Pasa-bandas'
                    graficarPasaBandas(app)
                case 'Rechaza-banda'
                    graficarRechazaBandas(app)
                otherwise
                %%PAra más funciones
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 20000;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 0.02;
            app.ProblemaDropDownLabel = uilabel(app.GridLayout);
            app.ProblemaDropDownLabel.Text = 'Problema';
            app.ProblemaDropDown = uidropdown(app.GridLayout);
            app.ProblemaDropDown.Items = {'Generar señales', 'Sumar señales', 'Transformada de fourier', 'Pasa-altas', 'Pasa-bajas', 'Pasa-bandas', 'Rechaza-banda'};
            app.ProblemaDropDown.Value = 'Generar señales';
            app.DominioDropDownLabel = uilabel(app.GridLayout);
            app.DominioDropDownLabel.Text = 'Dominio';
            app.DominioDropDown = uidropdown(app.GridLayout);
            app.DominioDropDown.Items = {'Tiempo', 'Frecuencia'};
            app.DominioDropDown.Value = 'Tiempo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica9p2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end